classdef guitar_tuner_v2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        currentHz              matlab.ui.control.Label
        CurrentFrequencyLabel  matlab.ui.control.Label
        Gauge                  matlab.ui.control.Gauge
        Label_3                matlab.ui.control.Label
        Label_2                matlab.ui.control.Label
        TunerLabel             matlab.ui.control.Label
        VeryLowLabel           matlab.ui.control.Label
        LowLabel               matlab.ui.control.Label
        VeryHighLabel          matlab.ui.control.Label
        HighLabel              matlab.ui.control.Label
        StandardLabel          matlab.ui.control.Label
        correctLamp            matlab.ui.control.Lamp
        StartButton            matlab.ui.control.Button
        GuitarLabel            matlab.ui.control.Label
        ButtonGroup            matlab.ui.container.ButtonGroup
        E1Button               matlab.ui.control.RadioButton
        B2Button               matlab.ui.control.RadioButton
        G3Button               matlab.ui.control.RadioButton
        D4Button               matlab.ui.control.RadioButton
        A5Button               matlab.ui.control.RadioButton
        E6Button               matlab.ui.control.RadioButton
        UIAxes2                matlab.ui.control.UIAxes
        UIAxes                 matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        
        E6 = [164 329.6]
        A = [110 220 440]
        D = [73.4 146.8 293.7]
        G = [98 196 392]
        B = [123.5 246.9 493.9]
        E1 = [164 329.6]

        note_select = [164 329.6];
        
        plotPause = true;
        data_lenght = 2000;
    end
    
    methods (Access = private) 
        
        function plotAudio(app)

            while ~app.plotPause

                time_do = app.getData();
                l = 1:length(time_do);
                plot(app.UIAxes2,l,time_do);
               
                Fs = 2000;

                Y = fft(time_do);
                L = length(time_do);
                P2 = abs(Y/L);
                P1 = P2(1:L/2+1);
                P1(2:end-1) = 2*P1(2:end-1);
                
                % Create the frequency axis in Hz
                f = Fs * (0:(L/2)) / L;
                
                % Plot the FFT result on the UIAxes
                plot(app.UIAxes, f(10:find(f == 500)), P1(10:find(f == 500)));

                app.updateGUI(f,P1);

            end

        end

        function updateGUI(app,freqes,ampts)
            ampts(1:10) = 0;
            ampts(find(freqes == 500):end) = 0;
            [~,index] = max(ampts);
            maxFreq = freqes(index);
            [~,index_space] = min(abs(maxFreq - app.note_select));
            space = maxFreq - app.note_select(index_space);
            app.currentHz.Text = string(maxFreq) + " Hz";
            
            if space <= 2 && space >= -2
                app.correctLamp.Color = [0 1 0];
            else
                app.correctLamp.Color = [1 0 0];
            end

            if maxFreq ~= 0
                currentValue = app.Gauge.Value;
                newValue = space * 10;
                
                interpolatedValues = linspace(currentValue, newValue, 50);

                app.updateGaugeValue(interpolatedValues)
            end

        end

        function updateGaugeValue(app,interpolatedValues)

            for i = 1:length(interpolatedValues)
                app.Gauge.Value = interpolatedValues(i);
                pause(0.02);
            end

        end
        
        function stopPlot(app)

            app.StartButton.Text="Start";
            app.StartButton.BackgroundColor="#3a4a0b";
            app.plotPause = true;

        end
        
        function Data = getData(app)
            port = serialport("COM9", 921600);
            data_list = zeros(1, app.data_lenght);
            i = 1;
            
            while i <= app.data_lenght
                data = readline(port);
                if str2double(data) < 3000 || i == 1
                    data_list(i) = str2double(data); % แปลงข้อมูลเป็นตัวเลข (หากมีความจำเป็น)
                else  
                    data_list(i) = data_list(i-1);
                end
                i = i + 1;
            end

            Data = data_list;
            clear port;
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            if app.StartButton.Text == "Start"
                app.StartButton.Text="Running...";
                app.StartButton.BackgroundColor="#fc7f03";

                app.plotPause = false;
                app.plotAudio();

            elseif app.StartButton.Text == "Running..."
                app.stopPlot();
            end
        end

        % Selection changed function: ButtonGroup
        function ButtonGroupSelectionChanged(app, event)
            % selectedButton = app.ButtonGroup.SelectedObject;
            if app.E6Button.Value == 1
                app.note_select = app.E6;
            elseif app.A5Button.Value == 1
                app.note_select = app.A;
            elseif app.D4Button.Value == 1
                app.note_select = app.D;
            elseif app.G3Button.Value == 1
                app.note_select = app.G;
            elseif app.B2Button.Value == 1
                app.note_select = app.B;
            elseif app.E1Button.Value == 1
                app.note_select = app.E1;
            end
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Frequency Domain (FFT)')
            xlabel(app.UIAxes, 'Frequency')
            ylabel(app.UIAxes, 'Amplitude')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Time Domain')
            xlabel(app.UIAxes2, 'Time')
            ylabel(app.UIAxes2, 'Amplitude')
            zlabel(app.UIAxes2, 'Z')
            app.ButtonGroup = uibuttongroup(app.UIFigure);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroupSelectionChanged, true);
            app.E6Button = uiradiobutton(app.ButtonGroup);
            app.E6Button.Text = 'E(6)';
            app.E6Button.Value = true;
            app.A5Button = uiradiobutton(app.ButtonGroup);
            app.A5Button.Text = 'A(5)';
            app.D4Button = uiradiobutton(app.ButtonGroup);
            app.D4Button.Text = 'D(4)';
            app.G3Button = uiradiobutton(app.ButtonGroup);
            app.G3Button.Text = 'G(3)';
            app.B2Button = uiradiobutton(app.ButtonGroup);
            app.B2Button.Text = 'B(2)';
            app.E1Button = uiradiobutton(app.ButtonGroup);
            app.E1Button.Text = 'E(1)';
            app.GuitarLabel = uilabel(app.UIFigure);
            app.GuitarLabel.Text = 'Guitar';
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.correctLamp = uilamp(app.UIFigure);
            app.StandardLabel = uilabel(app.UIFigure);
            app.StandardLabel.Text = 'Standard';
            app.HighLabel = uilabel(app.UIFigure);
            app.HighLabel.Text = 'High';
            app.VeryHighLabel = uilabel(app.UIFigure);
            app.VeryHighLabel.Text = 'Very High';
            app.LowLabel = uilabel(app.UIFigure);
            app.LowLabel.Text = 'Low';
            app.VeryLowLabel = uilabel(app.UIFigure);
            app.VeryLowLabel.Text = 'Very Low';
            app.TunerLabel = uilabel(app.UIFigure);
            app.TunerLabel.Text = 'Tuner';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '________';
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = '________';
            app.Gauge = uigauge(app.UIFigure, 'circular');
            app.Gauge.Limits = [-100 100];
            app.Gauge.MajorTicks = [-100 -80 -60 -40 -20 0 20 40 60 80 100];
            app.Gauge.MajorTickLabels = {''};
            app.Gauge.MinorTicks = [];
            app.CurrentFrequencyLabel = uilabel(app.UIFigure);
            app.CurrentFrequencyLabel.Text = {'Current'; 'Frequency'};
            app.currentHz = uilabel(app.UIFigure);
            app.currentHz.Text = '0 Hz';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = guitar_tuner_v2

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end